classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_UnitInfo                matlab.ui.container.Panel
        GridLayout22                  matlab.ui.container.GridLayout
        DropDown_UnitSystem           matlab.ui.control.DropDown
        Table_UnitSystem              matlab.ui.control.Table
        TextArea_Units                matlab.ui.control.TextArea
        PopUpPanel_StatCond           matlab.ui.container.Panel
        GridLayout18_13               matlab.ui.container.GridLayout
        RotationsDOFTextArea          matlab.ui.control.TextArea
        RotationsDOFTextAreaLabel     matlab.ui.control.Label
        yDOFTextArea                  matlab.ui.control.TextArea
        yDOFTextAreaLabel             matlab.ui.control.Label
        xDOFTextArea                  matlab.ui.control.TextArea
        xDOFTextAreaLabel             matlab.ui.control.Label
        KnotenTextArea_8              matlab.ui.control.TextArea
        KnotenTextArea_8Label         matlab.ui.control.Label
        PopUpPanel_InfluenceLine      matlab.ui.container.Panel
        GridLayout18_12               matlab.ui.container.GridLayout
        RichtungTextArea_5            matlab.ui.control.TextArea
        RichtungTextArea_5Label       matlab.ui.control.Label
        KnotenTextArea_6              matlab.ui.control.TextArea
        KnotenTextArea_6Label         matlab.ui.control.Label
        StelleTextArea                matlab.ui.control.TextArea
        StelleTextAreaLabel           matlab.ui.control.Label
        StabTextArea_3                matlab.ui.control.TextArea
        StabLabel                     matlab.ui.control.Label
        TypTextArea                   matlab.ui.control.TextArea
        TypLabel                      matlab.ui.control.Label
        PopUpPanel_Section            matlab.ui.container.Panel
        GridLayout18_11               matlab.ui.container.GridLayout
        BiegesteifTextArea            matlab.ui.control.TextArea
        BiegesteifTextAreaLabel       matlab.ui.control.Label
        DehnstarrTextArea             matlab.ui.control.TextArea
        DehnstarrTextAreaLabel        matlab.ui.control.Label
        TrgheitsmomentTextArea        matlab.ui.control.TextArea
        TrgheitsmomentTextAreaLabel   matlab.ui.control.Label
        FlcheTextArea                 matlab.ui.control.TextArea
        FlcheTextAreaLabel            matlab.ui.control.Label
        MaterialTextArea              matlab.ui.control.TextArea
        MaterialLabel                 matlab.ui.control.Label
        NameTextArea_2                matlab.ui.control.TextArea
        NameTextArea_2Label           matlab.ui.control.Label
        PopUpPanel_DistrLoad          matlab.ui.container.Panel
        GridLayout18_10               matlab.ui.container.GridLayout
        EndpositionTextArea           matlab.ui.control.TextArea
        EndpositionTextAreaLabel      matlab.ui.control.Label
        StartPositionTextArea         matlab.ui.control.TextArea
        StartPositionTextAreaLabel    matlab.ui.control.Label
        WertTextArea_3                matlab.ui.control.TextArea
        WertTextArea_3Label           matlab.ui.control.Label
        StabTextArea_2                matlab.ui.control.TextArea
        StabTextArea_2Label           matlab.ui.control.Label
        RichtungTextArea_3            matlab.ui.control.TextArea
        RichtungTextArea_3Label       matlab.ui.control.Label
        PopUpPanel_ForcedDispl        matlab.ui.container.Panel
        GridLayout18_9                matlab.ui.container.GridLayout
        WertTextArea_4                matlab.ui.control.TextArea
        WertLabel                     matlab.ui.control.Label
        RichtungTextArea_4            matlab.ui.control.TextArea
        RichtungTextArea_4Label       matlab.ui.control.Label
        KnotenTextArea_4              matlab.ui.control.TextArea
        KnotenTextArea_4Label         matlab.ui.control.Label
        PopUpPanel_PointLoad          matlab.ui.container.Panel
        GridLayout18_7                matlab.ui.container.GridLayout
        StartPositionTextArea_2       matlab.ui.control.TextArea
        StartpositionLabel            matlab.ui.control.Label
        WertTextArea_2                matlab.ui.control.TextArea
        WertTextArea_2Label           matlab.ui.control.Label
        StabTextArea                  matlab.ui.control.TextArea
        StabTextAreaLabel             matlab.ui.control.Label
        RichtungTextArea_2            matlab.ui.control.TextArea
        RichtungLabel                 matlab.ui.control.Label
        PopUpPanel_NodalLoad          matlab.ui.container.Panel
        GridLayout18_6                matlab.ui.container.GridLayout
        WertTextArea                  matlab.ui.control.TextArea
        WertTextAreaLabel             matlab.ui.control.Label
        RichtungTextArea              matlab.ui.control.TextArea
        RichtungTextAreaLabel         matlab.ui.control.Label
        KnotenTextArea_5              matlab.ui.control.TextArea
        KnotenTextArea_5Label         matlab.ui.control.Label
        PopUpPanel_Spring             matlab.ui.container.Panel
        GridLayout18_5                matlab.ui.container.GridLayout
        BetragTextArea                matlab.ui.control.TextArea
        BetragTextAreaLabel           matlab.ui.control.Label
        FederTextArea                 matlab.ui.control.TextArea
        FederTextAreaLabel            matlab.ui.control.Label
        KnotenTextArea_2              matlab.ui.control.TextArea
        KnotenTextArea_2Label         matlab.ui.control.Label
        PopUpPanel_Support            matlab.ui.container.Panel
        GridLayout18_4                matlab.ui.container.GridLayout
        Image2                        matlab.ui.control.Image
        LagerungTextArea              matlab.ui.control.TextArea
        LagerungTextAreaLabel         matlab.ui.control.Label
        KnotenTextArea                matlab.ui.control.TextArea
        KnotenLabel                   matlab.ui.control.Label
        PopUpPanel_Beam               matlab.ui.container.Panel
        GridLayout18_3                matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        GelenkStabendeTextArea        matlab.ui.control.TextArea
        GelenkStabendeLabel           matlab.ui.control.Label
        GelenkStabAnfangTextArea      matlab.ui.control.TextArea
        GelenkStabAnfangLabel         matlab.ui.control.Label
        QuerschnittTextArea           matlab.ui.control.TextArea
        QuerschnittLabel              matlab.ui.control.Label
        StartKnotenTextArea           matlab.ui.control.TextArea
        StartKnotenLabel              matlab.ui.control.Label
        EndKnotenTextArea             matlab.ui.control.TextArea
        EndKnotenLabel                matlab.ui.control.Label
        PopUpPanel_Node               matlab.ui.container.Panel
        GridLayout18_2                matlab.ui.container.GridLayout
        yPosTextArea                  matlab.ui.control.TextArea
        yPosLabel                     matlab.ui.control.Label
        xPosTextArea                  matlab.ui.control.TextArea
        xPosLabel                     matlab.ui.control.Label
        PopUpPanel_Material           matlab.ui.container.Panel
        GridLayout18                  matlab.ui.container.GridLayout
        EModulTextArea                matlab.ui.control.TextArea
        EModulLabel                   matlab.ui.control.Label
        NameTextArea                  matlab.ui.control.TextArea
        NameLabel                     matlab.ui.control.Label
        Panel_Material                matlab.ui.container.Panel
        GridLayout9_2                 matlab.ui.container.GridLayout
        Button_ResetTables_3          matlab.ui.control.Button
        Button_ClosePanel_5           matlab.ui.control.Button
        InfoButton_9                  matlab.ui.control.Button
        SubPanel_Material             matlab.ui.container.Panel
        GridLayout14_2                matlab.ui.container.GridLayout
        RemoveRowButton_9             matlab.ui.control.Button
        AddRowButton_9                matlab.ui.control.Button
        Table_Material                matlab.ui.control.Table
        Panel_Section                 matlab.ui.container.Panel
        GridLayout9_3                 matlab.ui.container.GridLayout
        Button_ResetTables_4          matlab.ui.control.Button
        Button_ClosePanel_4           matlab.ui.control.Button
        InfoButton_10                 matlab.ui.control.Button
        SubPanel_Section              matlab.ui.container.Panel
        GridLayout14_3                matlab.ui.container.GridLayout
        Table_Section                 matlab.ui.control.Table
        RemoveRowButton_10            matlab.ui.control.Button
        AddRowButton_10               matlab.ui.control.Button
        Panel_Beam                    matlab.ui.container.Panel
        GridLayout8_2                 matlab.ui.container.GridLayout
        Button_ResetTables_1          matlab.ui.control.Button
        Button_ClosePanel_1           matlab.ui.control.Button
        InfoButton_1                  matlab.ui.control.Button
        InfoButton_2                  matlab.ui.control.Button
        InfoButton_3                  matlab.ui.control.Button
        InfoButton_4                  matlab.ui.control.Button
        SubPanel_Node                 matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        RemoveRowButton_1             matlab.ui.control.Button
        AddRowButton_1                matlab.ui.control.Button
        Table_Node                    matlab.ui.control.Table
        SubPanel_Beam                 matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        RemoveRowButton_2             matlab.ui.control.Button
        AddRowButton_2                matlab.ui.control.Button
        Table_Beam                    matlab.ui.control.Table
        SubPanel_Support              matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        RemoveRowButton_3             matlab.ui.control.Button
        AddRowButton_3                matlab.ui.control.Button
        Table_Support                 matlab.ui.control.Table
        SubPanel_Spring               matlab.ui.container.Panel
        GridLayout13                  matlab.ui.container.GridLayout
        RemoveRowButton_4             matlab.ui.control.Button
        AddRowButton_4                matlab.ui.control.Button
        Table_Spring                  matlab.ui.control.Table
        Panel_Actions                 matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        Button_ResetTables_2          matlab.ui.control.Button
        Button_ClosePanel_2           matlab.ui.control.Button
        InfoButton_5                  matlab.ui.control.Button
        InfoButton_6                  matlab.ui.control.Button
        InfoButton_7                  matlab.ui.control.Button
        InfoButton_8                  matlab.ui.control.Button
        SubPanel_NodalLoad            matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        RemoveRowButton_5             matlab.ui.control.Button
        AddRowButton_5                matlab.ui.control.Button
        Table_NodalLoad               matlab.ui.control.Table
        SubPanel_PointLoad            matlab.ui.container.Panel
        GridLayout15                  matlab.ui.container.GridLayout
        RemoveRowButton_6             matlab.ui.control.Button
        AddRowButton_6                matlab.ui.control.Button
        Table_PointLoad               matlab.ui.control.Table
        SubPanel_DistrLoad            matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        RemoveRowButton_7             matlab.ui.control.Button
        AddRowButton_7                matlab.ui.control.Button
        Table_DistrLoad               matlab.ui.control.Table
        SubPanel_ForcedDispl          matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        RemoveRowButton_8             matlab.ui.control.Button
        AddRowButton_8                matlab.ui.control.Button
        Table_ForcedDispl             matlab.ui.control.Table
        Panel_Result                  matlab.ui.container.Panel
        GridLayout9_4                 matlab.ui.container.GridLayout
        InfoButton_12                 matlab.ui.control.Button
        InfoButton_11                 matlab.ui.control.Button
        SubPanel_StatCond             matlab.ui.container.Panel
        GridLayout19                  matlab.ui.container.GridLayout
        RemoveRowButton_11            matlab.ui.control.Button
        AddRowButton_11               matlab.ui.control.Button
        Table_StatCond                matlab.ui.control.Table
        JaNeinCheckBox                matlab.ui.control.CheckBox
        SubPanel_InfluenceLine        matlab.ui.container.Panel
        GridLayout14_4                matlab.ui.container.GridLayout
        Table_InfluenceLine_2         matlab.ui.control.Table
        Table_InfluenceLine_1         matlab.ui.control.Table
        Dropdown_TypEL                matlab.ui.control.DropDown
        TypLabel_2                    matlab.ui.control.Label
        Button_Calculate              matlab.ui.control.Button
        DropDown_GewnschterOutput     matlab.ui.control.DropDown
        GewnschterOutputLabel         matlab.ui.control.Label
        Button_ClosePanel_3           matlab.ui.control.Button
        GridLayout                    matlab.ui.container.GridLayout
        Panel_SystemViewer            matlab.ui.container.Panel
        GridLayout20                  matlab.ui.container.GridLayout
        SystemViewer                  matlab.ui.control.UIAxes
        Button_Inputspeichern         matlab.ui.control.Button
        Button_Inputladen             matlab.ui.control.Button
        Button_Actions                matlab.ui.control.Button
        Button_Material               matlab.ui.control.Button
        Button_Result                 matlab.ui.control.Button
        Button_Section                matlab.ui.control.Button
        Button_Beam                   matlab.ui.control.Button
        ContextMenu_MaterialTable     matlab.ui.container.ContextMenu
        HinzufgenMenu_Material        matlab.ui.container.Menu
        LschenMenu_Material           matlab.ui.container.Menu
        ContextMenu_SectionTable      matlab.ui.container.ContextMenu
        HinzufgenMenu_Section         matlab.ui.container.Menu
        LschenMenu_Section            matlab.ui.container.Menu
        ContextMenu_NodeTable         matlab.ui.container.ContextMenu
        HinzufgenMenu_Node            matlab.ui.container.Menu
        LschenMenu_Node               matlab.ui.container.Menu
        ContextMenu_BeamTable         matlab.ui.container.ContextMenu
        HinzufgenMenu_Beam            matlab.ui.container.Menu
        LschenMenu_Beam               matlab.ui.container.Menu
        ContextMenu_SupportTable      matlab.ui.container.ContextMenu
        HinzufgenMenu_Support         matlab.ui.container.Menu
        LschenMenu_Support            matlab.ui.container.Menu
        ContextMenu_SpringTable       matlab.ui.container.ContextMenu
        HinzufgenMenu_Spring          matlab.ui.container.Menu
        LschenMenu_Spring             matlab.ui.container.Menu
        ContextMenu_NodalLoadTable    matlab.ui.container.ContextMenu
        HinzufgenMenu_NodalLoad       matlab.ui.container.Menu
        LschenMenu_NodalLoad          matlab.ui.container.Menu
        ContextMenu_PointLoadTable    matlab.ui.container.ContextMenu
        HinzufgenMenu_PointLoad       matlab.ui.container.Menu
        LschenMenu_PointLoad          matlab.ui.container.Menu
        ContextMenu_DistrLoadTable    matlab.ui.container.ContextMenu
        HinzufgenMenu_DistrLoad       matlab.ui.container.Menu
        LschenMenu_DistrLoad          matlab.ui.container.Menu
        ContextMenu_ForcedDisplTable  matlab.ui.container.ContextMenu
        HinzufgenMenu_ForcedDispl     matlab.ui.container.Menu
        LschenMenu_ForcedDispl        matlab.ui.container.Menu
        ContextMenu_StatCondTable     matlab.ui.container.ContextMenu
        HinzufgenMenu_StatCond        matlab.ui.container.Menu
        LschenMenu_StatCond           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Model % Stores the entire Model after calculation
        Panels struct % maps key -> panel
        CurrentPanel % handles the current visible panel
        Tables struct % maps key -> table
        PopUpPanels struct % maps key -> popuppanel
        CurrentPopUpPanel % handles the current visible popuppanel
        rowNumber % rownNumber of the highlighted cell, used to remove rows
        ColumnTemplates = struct() % formatting templates used for dropdowns in cells
        Dropdown_MaterialNames % List of Material names used for dynamically updating dropdown list
        Dropdown_SectionNames % List of Section names used for dynamically updating dropdown list
        Dropdown_NodeNumbers % List of Node numbers used for dynamically updating dropdown list
        Dropdown_BeamNumbers % List of the Beam numbers used for dynamically updating dropdown list
        Dropdown_SupportTypes % List of support types to choose from
        Dropdown_Directions % List of coordinate directions to choose from
        Dropdown_Directions_xy % List of coordinate directions (only xy for distrloads)
        Dropdown_HingeTypes % List of hinge types to choose from
        Dropdown_SpringTypes % List of spring types to choose from
        Dropdown_ILTypes % List of influence line types to choose from
        IconButtons matlab.ui.control.Button   % all buttons that have light/dark icons
        PopupFig  matlab.ui.Figure % Pop up window used for explanations
        GridLayout25
        GridLayout24
        GridLayout23
        TabGroup_PopupFig
        Tab_Info
        Tab_Units
        HiddenLegendNames string = strings(0,1); % list of legend entries that are hidden
    end 
    
    methods (Access = private)
        %All the functions are placed in separate files
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % create structs used for mapping tags to buttons
            % app.Panels.(key) calls corresponding panel
            app.Panels = struct( ...
                'Material',     app.Panel_Material, ...
                'Section',      app.Panel_Section, ...
                'Beam',         app.Panel_Beam, ...
                'Actions',      app.Panel_Actions, ...
                'Result',      app.Panel_Result);

            app.Tables = struct( ...
                'MaterialTable',      app.Table_Material, ...
                'SectionTable',       app.Table_Section, ...
                'NodeTable',          app.Table_Node, ...
                'BeamTable',          app.Table_Beam, ...
                'SupportTable',       app.Table_Support, ...
                'SpringTable',        app.Table_Spring, ...
                'NodalLoadTable',     app.Table_NodalLoad, ...
                'PointLoadTable',     app.Table_PointLoad, ...
                'DistrLoadTable',     app.Table_DistrLoad, ...
                'ForcedDisplTable',   app.Table_ForcedDispl, ...
                'InfluenceLineTable1', app.Table_InfluenceLine_1, ...
                'InfluenceLineTable2', app.Table_InfluenceLine_2, ...
                'StatCondTable',       app.Table_StatCond);

            app.PopUpPanels = struct( ...
                'MaterialPopUpPanel',      app.PopUpPanel_Material, ...
                'SectionPopUpPanel',       app.PopUpPanel_Section, ...
                'NodePopUpPanel',          app.PopUpPanel_Node, ...
                'BeamPopUpPanel',          app.PopUpPanel_Beam, ...
                'SupportPopUpPanel',       app.PopUpPanel_Support, ...
                'SpringPopUpPanel',        app.PopUpPanel_Spring, ...
                'NodalLoadPopUpPanel',     app.PopUpPanel_NodalLoad, ...
                'PointLoadPopUpPanel',     app.PopUpPanel_PointLoad, ...
                'DistrLoadPopUpPanel',     app.PopUpPanel_DistrLoad, ...
                'ForcedDisplPopUpPanel',   app.PopUpPanel_ForcedDispl, ...
                'InfluenceLinePopUpPanel', app.PopUpPanel_InfluenceLine, ...
                'StatCondPopUpPanel',      app.PopUpPanel_StatCond);
            
            app.Dropdown_SupportTypes = { ...
                'Eingespannt', ...             % 1
                'Gelenkig', ...                % 2
                'Rolllager y-Richtung', ...    % 3
                'Rolllager x-Richtung', ...    % 4
                'Gleitlager y-Richtung', ...   % 5
                'Gleitlager x-Richtung'};      % 6

            app.Dropdown_Directions = { ...
                'x-Richtung', ...              % 1
                'y-Richtung', ...              % 2
                'Rotation'};                   % 3

            app.Dropdown_Directions_xy = { ...
                'x-Richtung', ...              % 1
                'y-Richtung'};                 % 2

            app.Dropdown_HingeTypes = { ...
                'kein Gelenk', ...             % 0
                'Normalkraftgelenk', ...       % 1
                'Querkraftgelenk', ...         % 2
                'Momentengelenk'};             % 3

            app.Dropdown_SpringTypes = { ...
                'Horizontalfeder', ...        % 1
                'Vertikalfeder', ...          % 2 
                'Rotationsfeder'};              % 3
            
            app.Dropdown_ILTypes = { ...
                'Normalkraft', ...             % 1
                'Querkraft', ...               % 2
                'Biegemoment'...               % 3
                'Lagerreaktion'};              % 4

            % table templates
            % Needeed for assigning dropdown menus to the tables 
            addTemplate(app, "MaterialTable", ...         % table name
                {'char', 'numeric'}, ...                  % column types
                {''    , []       });                     % emtpy rows
                                              
            addTemplate(app, "SectionTable", ...
                {'char', app.Dropdown_MaterialNames, 'numeric','numeric','logical','logical'}, ...
                {''    , '– wählen –'   , []       , []       , false      , false      });

            addTemplate(app, "NodeTable", ...
                {'numeric','numeric'}, ...
                {[]       , []      });

            addTemplate(app, "BeamTable", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_NodeNumbers,app.Dropdown_SectionNames,app.Dropdown_HingeTypes,app.Dropdown_HingeTypes}, ...
                {'– wählen –'       , '– wählen –'      , '– wählen –'   , '– wählen –'      , '– wählen –'      });

            addTemplate(app, "SupportTable", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_SupportTypes}, ...
                {'– wählen –'       , '– wählen –'        });

            addTemplate(app, "SpringTable", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_SpringTypes,'numeric'}, ...
                {'– wählen –'       , '– wählen –'      , []      });

            addTemplate(app, "NodalLoadTable", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_Directions,'numeric'}, ...
                {'– wählen –'       , '– wählen –'      , []      });

            addTemplate(app, "PointLoadTable", ...
                {app.Dropdown_BeamNumbers,app.Dropdown_Directions,'numeric','numeric'}, ...
                {'– wählen –'       , '– wählen –'      , []      , []      });

            addTemplate(app, "DistrLoadTable", ...
                {app.Dropdown_BeamNumbers,app.Dropdown_Directions_xy,'numeric','numeric','numeric'}, ...
                {'– wählen –'       , '– wählen –'      , []      , []      , []      });

            addTemplate(app, "ForcedDisplTable", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_Directions,'numeric'}, ...
                {'– wählen –'       , '– wählen –'      , []      });

            addTemplate(app, "InfluenceLineTable1", ...
                {app.Dropdown_BeamNumbers,'numeric'}, ...
                {'– wählen –'      , []      });

            addTemplate(app, "InfluenceLineTable2", ...
                {app.Dropdown_NodeNumbers,app.Dropdown_Directions}, ...
                {'– wählen –'      ,'– wählen –'       });

            addTemplate(app, "StatCondTable", ...
                {app.Dropdown_NodeNumbers,'logical','logical','logical'}, ...
                {'– wählen –'      ,false, false, false       });

            % create the popup window for table descriptions
            app.PopupFig = uifigure('Visible', 'off');
            app.PopupFig.WindowStyle = 'alwaysontop';
            app.PopupFig.Name = 'Info Fenster';
            app.GridLayout25 = uigridlayout(app.PopupFig);
            app.GridLayout25.ColumnWidth = {'1x'};
            app.GridLayout25.RowHeight = {'1x'};
            app.TabGroup_PopupFig = uitabgroup(app.GridLayout25);
            app.Tab_Info = uitab(app.TabGroup_PopupFig);
            app.Tab_Info.Title = 'Tabelleninfo';
            app.Tab_Units = uitab(app.TabGroup_PopupFig);
            app.Tab_Units.Title = 'Einheiten';
            app.GridLayout24 = uigridlayout(app.Tab_Info);
            app.GridLayout24.ColumnWidth = {'1x'};
            app.GridLayout24.RowHeight = {'1x'};
            app.GridLayout23 = uigridlayout(app.Tab_Units);
            app.GridLayout23.ColumnWidth = {'1x'};
            app.GridLayout23.RowHeight = {'1x'};
            app.PopupFig.CloseRequestFcn = createCallbackFcn(app, @PopupFigCloseRequest, true);

            % initiate unit-info panel in correct location
            app.Panel_UnitInfo.Parent = app.GridLayout23;
            app.Table_UnitSystem.Data = {'N/m^2 (Pa)', 'm^2', 'm^4', 'm', 'N', 'Nm', 'N/m', 'N/m'};

            % initiate all panels in correct location
            panelList = struct2cell(app.Panels);
            for i = 1:numel(panelList)
                p = panelList{i};
                p.Parent       = app.GridLayout;
                p.Layout.Row    = [1 10];
                p.Layout.Column = 3;
            end

            % initiate all popuppanels in correct location
            popuppanelList = struct2cell(app.PopUpPanels);
            for i = 1:numel(popuppanelList)
                p = popuppanelList{i};
                p.Parent       = app.GridLayout24;
                p.Layout.Row    = 1;
                p.Layout.Column = 1;
            end
            
            % initiate all tables with correct format
            tableNames = fieldnames(app.Tables);
            for i = 1:numel(tableNames)
                name = tableNames{i};
                t    = app.Tables.(name);
                t.ColumnFormat = app.ColumnTemplates.(name).format;
            end
            
            % Influence line tables only have one row
            app.Table_InfluenceLine_1.Data = [app.Table_InfluenceLine_1.Data; app.ColumnTemplates.InfluenceLineTable1.emptyRow];
            app.Table_InfluenceLine_2.Data = [app.Table_InfluenceLine_2.Data; app.ColumnTemplates.InfluenceLineTable2.emptyRow];
            
            % all icons used for swiching themes
            app.IconButtons = [
                app.Button_ClosePanel_1
                app.Button_ClosePanel_2
                app.Button_ClosePanel_3
                app.Button_ClosePanel_4
                app.Button_ClosePanel_5
                app.AddRowButton_1
                app.AddRowButton_2
                app.AddRowButton_3
                app.AddRowButton_4
                app.AddRowButton_5
                app.AddRowButton_6
                app.AddRowButton_7
                app.AddRowButton_8
                app.AddRowButton_9
                app.AddRowButton_10
                app.RemoveRowButton_1
                app.RemoveRowButton_2
                app.RemoveRowButton_3
                app.RemoveRowButton_4
                app.RemoveRowButton_5
                app.RemoveRowButton_6
                app.RemoveRowButton_7
                app.RemoveRowButton_8
                app.RemoveRowButton_9
                app.RemoveRowButton_10
                app.InfoButton_1
                app.InfoButton_2
                app.InfoButton_3
                app.InfoButton_4
                app.InfoButton_5
                app.InfoButton_6
                app.InfoButton_7
                app.InfoButton_8
                app.InfoButton_9
                app.InfoButton_10
                app.InfoButton_11
                app.InfoButton_12
                app.Button_Inputladen
                app.Button_Inputspeichern
                app.Button_ResetTables_1
                app.Button_ResetTables_2
                app.Button_ResetTables_3
                app.Button_ResetTables_4
            ];
            updateIconsForTheme(app);
            
            % Custom icon buttons at the top of the live viewer
            tb = axtoolbar(app.SystemViewer, {'export','pan','zoomin','zoomout'});
            btnHome = axtoolbarbtn(tb, 'push');
            btnHome.Icon = 'restoreview';
            btnHome.Tooltip = 'Restore view';
            btnHome.ButtonPushedFcn = @(~,~) onRestoreView(app);
            btnToggleLegend = axtoolbarbtn(tb, 'push');
            btnToggleLegend.Icon = fullfile(fileparts(mfilename('fullpath')), 'icons', 'icon-mat-format-list-bulleted.png');
            btnToggleLegend.Tooltip = 'Toggle Legend';
            btnToggleLegend.ButtonPushedFcn = @(~,~) ToggleLegendButtonPushed(app);
        end

        % Button pushed function: Button_Actions, Button_Beam, 
        % ...and 3 other components
        function Button_LeftMenuPushed(app, event)
            % get tag of the pressed button
            newKey = string(event.Source.Tag);

            % If the button belongs to the current visible panel -> do
            % nothing
            if ~isempty(app.CurrentPanel) && app.CurrentPanel == app.Panels.(newKey)
                return;
            end
            
            %Validate the current panel before switching
            if ~app.validateCurrentPanel()
                % Validation or guiToInput/validateMainPanel failed -> stay where we are
                return;
            end

            % make the old panel invisible
            if ~isempty(app.CurrentPanel) && isvalid(app.CurrentPanel)
                app.CurrentPanel.Visible = 'off';
            end

            % resize system viewer
            % note: workaround to get the system viewer to resize properly:
            % first set axis to normal and have systemviewersizechanged set axis back to
            % equal. Doing both in the same function doesn't work for some
            % reason. Together with the custom home button, all edge cases
            % should be covered.
            if isempty(app.CurrentPanel)
                axis(app.SystemViewer, 'normal');
            end
            app.Panel_SystemViewer.Layout.Column = [4 5];

            newPanel = app.Panels.(newKey);

            newPanel.Visible = 'on';

            app.CurrentPanel = newPanel;

        end

        % Button pushed function: Button_ClosePanel_1, 
        % ...and 4 other components
        function Button_BackPushed(app, event)
            %validate panel before closing it
            if ~app.validateCurrentPanel()
                % Panel has errors -> don't close it
                return;
            end
            % resize system viewer
            axis(app.SystemViewer, 'normal');
            app.Panel_SystemViewer.Layout.Column = [3 5];

            event.Source.Parent.Parent.Visible = 'off';
            
            % remove current panel
            app.CurrentPanel = [];
        end

        % Button pushed function: Button_Calculate
        function Button_CalculatePushed(app, event)
           fname = fullfile(tempdir, 'DSM_Warnings.txt');   % safer than bare 'Warnings'
            if exist(fname, 'file')
                delete(fname);
            end

            [in, inputIssues] = guiToInput(app, true);

            if ~inputIssues.ok
                msg = strjoin(string(inputIssues.messages), newline);
                uialert(app.UIFigure, msg, 'Eingabefehler');
                return;
            end
            try
                diary(fname);

                opts = struct('throwOnError', true); %", 'verbose', true" if you want errors thrown
                [app.Model, status, Meldung, issues] = MatrizenStatik(in, opts);
                %show Model in workspace
                assignin('base', 'Model',   app.Model);
                diary off
                
                warnText = fileread(fname);

                if ~isempty(strtrim(warnText))
                    uialert(app.UIFigure, warnText, 'Warnung', 'Icon','warning');
                end
             
                disp('Run completed successfully');

                if exist(fname, 'file')
                    delete(fname);
                end
            catch ME
                if exist(fname, 'file')
                    delete(fname);
                end
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Callback function: AddRowButton_1, AddRowButton_10, 
        % ...and 20 other components
        function Button_AddRowPushed(app, event)
            % determine key based on Tag
            key = string(event.Source.Tag);

            tpl = app.ColumnTemplates.(key);

            
            % update Node and beam dropdown list when row is added and
            % check for dependencies
            if key == "SectionTable"
                % Check if there is at least one real material name
                matNames = app.Table_Material.Data;

                if isempty(matNames)
                    uialert(app.UIFigure, ...
                        "Bitte definieren Sie zuerst mindestens ein Material, bevor Sie einen Querschnitt anlegen.", ...
                        "Kein Material vorhanden");
                    return;
                end
                matNames = matNames(:,1).';
                matNames = matNames(matNames ~= "");

                if isempty(matNames)
                    uialert(app.UIFigure, ...
                        "Bitte definieren Sie zuerst mindestens ein Material, bevor Sie einen Querschnitt anlegen.", ...
                        "Kein Material vorhanden");
                    return;
                end

            elseif key == "NodeTable"
                NodeTableChanged(app)
            elseif key == "BeamTable"
                % Check if there is at least one real material name
                secNames = app.Table_Section.Data;
                
                if isempty(secNames)
                    uialert(app.UIFigure, ...
                        "Bitte definieren Sie zuerst mindestens einen Querschnitt, bevor Sie einen Stab anlegen.", ...
                        "Kein Querschnitt vorhanden");
                    return;
                end
                secNames = secNames(:,1).';
                secNames = secNames(secNames ~= "");

                if isempty(secNames)
                    uialert(app.UIFigure, ...
                        "Bitte definieren Sie zuerst mindestens einen Querschnitt, bevor Sie einen Stab anlegen.", ...
                        "Kein Querschnitt vorhanden");
                    return;
                end

                BeamTableChanged(app)
            end
            
            app.Tables.(key).Data = [app.Tables.(key).Data; tpl.emptyRow];
        end

        % Callback function: LschenMenu_Beam, LschenMenu_DistrLoad, 
        % ...and 20 other components
        function Button_RemoveRowPushed(app, event)
            % determine key based on Tag
            key = string(event.Source.Tag);
            
            % nothing selected --> pushing button does nothing
            if isempty(app.rowNumber)
                return
            end

            % keep only rows that exist in THIS table
            n = size(app.Tables.(key).Data, 1);
            rows = app.rowNumber;
            rows = rows(rows >= 1 & rows <= n);

            if isempty(rows)
                % selection belongs to another table (or table shrunk)
                return
            end
        
            %remove selected row
            app.Tables.(key).Data(app.rowNumber,:) = [];

            % update dropdown list when row is removed
            if key == "NodeTable"
                NodeTableChanged(app)
            elseif key == "BeamTable"
                BeamTableChanged(app)
            elseif key == "MaterialTable"
                MaterialTableChanged(app)
            elseif key == "SectionTable"
                SectionTableChanged(app)
            end
            
            % clear stale selection
            app.rowNumber = [];
            updateLiveViewer(app)
        end

        % Cell selection callback: Table_Beam, Table_DistrLoad, 
        % ...and 10 other components
        function Table_GetRowCellSelection(app, event)
            % do nothing when nothing is selected
            if isempty(event.Indices)
                app.rowNumber = [];   % nothing selected
                return;
            end

            % extract unique row numbers from all selected cells
            app.rowNumber = unique(event.Indices(:, 1));
         
        end

        % Value changed function: DropDown_GewnschterOutput
        function DropDown_GewnschterOutputValueChanged(app, event)
            value = app.DropDown_GewnschterOutput.Value;
            if value == "Einflusslinie"
                app.SubPanel_InfluenceLine.Visible = 'on';
                app.InfoButton_11.Visible = 'on';
                app.InfoButton_11.Enable = 'on';
                app.SubPanel_StatCond.Visible = 'off';
                app.InfoButton_12.Visible = 'off';
                app.InfoButton_12.Enable = 'off';
            else
                app.SubPanel_StatCond.Visible = 'on';
                app.InfoButton_12.Visible = 'on';
                app.InfoButton_12.Enable = 'on';
                app.SubPanel_InfluenceLine.Visible = 'off';
                app.InfoButton_11.Visible = 'off';
                app.InfoButton_11.Enable = 'off';
            end

        end

        % Cell edit callback: Table_Material, Table_Section
        function Table_UpdateDropdown(app, event)
            col = event.Indices(2);

            if col ~= 1
                return
            end

            key = string(event.Source.Tag);
            
            if key == "MaterialTable"
                MaterialTableChanged(app)
            elseif key == "SectionTable"
                SectionTableChanged(app)
            end
        end

        % Value changed function: Dropdown_TypEL
        function Dropdown_TypELValueChanged(app, event)
            value = app.Dropdown_TypEL.Value;
            if value == "Lagerreaktion"
                app.Table_InfluenceLine_2.Visible = "on";
            else
                app.Table_InfluenceLine_2.Visible = "off";
            end
        end

        % Theme changed function: UIFigure
        function UIFigureThemeChanged(app, event)
            updateIconsForTheme(app);
        end

        % Button pushed function: InfoButton_1, InfoButton_10, 
        % ...and 10 other components
        function Button_InfoPushed(app, event)
            key = string(event.Source.Tag);

            % If first time info button being pressed
            if strcmp(app.PopupFig.Visible, 'off')
                btnScreenPos = getpixelposition(event.Source, true);
                popupX = btnScreenPos(1);
                popupY = btnScreenPos(2) + btnScreenPos(4);
                app.PopupFig.Position = [popupX popupY 270 270];
                app.PopupFig.Visible  = 'on';
            end
            
            % If the button belongs to the current visible panel -> do
            % nothing
            if ~isempty(app.CurrentPopUpPanel) && app.CurrentPopUpPanel == app.PopUpPanels.(key)
                return;
            end
            % make the old panel invisible
            if ~isempty(app.CurrentPopUpPanel) && isvalid(app.CurrentPopUpPanel)
                app.CurrentPopUpPanel.Visible = 'off';
            end

            newPopUpPanel = app.PopUpPanels.(key);
            
            uistack(newPopUpPanel, 'top');
            newPopUpPanel.Visible = 'on';

            app.CurrentPopUpPanel = newPopUpPanel;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.PopupFig)
            delete(app.UIFigure)
        end

        % Button pushed function: Button_Inputladen
        function Button_InputladenPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Eingabedatei laden');
            if isequal(file, 0)
                return;  % user cancelled
            end

            S = load(fullfile(path, file));
            importGUIState(app, S);
            app.updateLiveViewer();
            % bring app window to front
            figure(app.UIFigure);
        end

        % Button pushed function: Button_Inputspeichern
        function Button_InputspeichernPushed(app, event)
            [file, path] = uiputfile('*.mat', 'Eingabedatei speichern');
            if isequal(file, 0)
                return;  % user cancelled
            end

            S = exportGUIState(app);
            save(fullfile(path, file), '-struct', 'S');
        end

        % Value changed function: JaNeinCheckBox
        function JaNeinCheckBoxValueChanged(app, event)
            value = app.JaNeinCheckBox.Value;
            if value
                app.Table_StatCond.Visible = 'on';
                app.RemoveRowButton_11.Visible = 'on';
                app.AddRowButton_11.Visible = 'on';
            else
                app.Table_StatCond.Visible = 'off';
                app.RemoveRowButton_11.Visible = 'off';
                app.AddRowButton_11.Visible = 'off';
            end
        end

        % Cell edit callback: Table_Beam, Table_DistrLoad, 
        % ...and 5 other components
        function Table_CellEdit(app, event)
            % Generic CellEditCallback for all input tables.
            % If MATLAB itself rejected the value, do nothing
            if ~isempty(event.Error) || isempty(event.NewData)
                return;
            end

            tbl = event.Source;
            key = string(tbl.Tag);      % e.g. "NodeTable", "BeamTable", ...
            row = event.Indices(1);
            % Convert UI data -> table, same logic as guiToInput
            T = app.uiDataToTable(tbl);

            switch key
                case "NodeTable"
                    % minimum for visualization: xPos + yPos
                    required = {'xPos','yPos'};

                    if rowIsComplete(app, T, row, required)
                        app.NodeTableChanged();
                        [in, issues1] = guiToInput(app, false);
                        in.Knoten = dropIncompleteRows(app, in.Knoten, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "BeamTable"
                    % basic visualisation: StartKnoten + EndKnoten
                    required = {'StartKnoten','EndKnoten','Querschnitt','GelenkStabAnfang','GelenkStabende'};

                    if rowIsComplete(app, T, row, required)
                        app.BeamTableChanged();
                        [in, issues1] = guiToInput(app, false);
                        in.Staebe = dropIncompleteRows(app, in.Staebe, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "SupportTable"
                    % Knoten + Lagerung
                    required = {'Knoten','Lagerung'};

                    if rowIsComplete(app, T, row, required)
                        [in, issues1] = guiToInput(app, false);
                        in.Lager = dropIncompleteRows(app, in.Lager, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "SpringTable"
                    % Knoten + Feder + Betrag
                    required = {'Knoten','Feder','Betrag'};

                    if rowIsComplete(app, T, row, required)
                        [in, issues1] = guiToInput(app, false);
                        in.Feder = dropIncompleteRows(app, in.Feder, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "NodalLoadTable"
                    % Knoten + Richtung + Wert
                    required = {'Knoten','Richtung','Wert'};

                    if rowIsComplete(app, T, row, required)
                        [in, issues1] = guiToInput(app, false);
                        in.KnotenLasten = dropIncompleteRows(app, in.KnotenLasten, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "PointLoadTable"
                    % Stab + Richtung + Wert + StartPosition
                    required = {'Stab','Richtung','Wert','StartPosition'};
                    
                    if rowIsComplete(app, T, row, required)
                        [in, issues1] = guiToInput(app, false);
                        in.StabLasten_konzentriert = dropIncompleteRows(app, in.StabLasten_konzentriert, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end

                case "DistrLoadTable"
                    % Stab + Richtung + Wert + StartPosition + EndPosition
                    required = {'Stab','Richtung','Wert','StartPosition','EndPosition'};

                    if rowIsComplete(app, T, row, required)
                        [in, issues1] = guiToInput(app, false);
                        in.StabLasten_verteilt = dropIncompleteRows(app, in.StabLasten_verteilt, required);
                        if validateCurrentTable(app, key, in, issues1)
                            updateLiveViewer(app, in);
                        end
                    end
                    
                %not needed
                case "MaterialTable"
                    app.Table_UpdateDropdown(event);
                    app.updateLiveViewer();
                %not needed
                case "SectionTable"
                    app.Table_UpdateDropdown(event);
                    app.updateLiveViewer();

                otherwise
                    % For other tables (e.g. ForcedDispl), you can either ignore,
                    % or just always update:
                    %app.updateLiveViewer();
            end
        end

        % Button pushed function: Button_ResetTables_1, 
        % ...and 3 other components
        function Button_resetTablesPushed(app, event)
            panelKey = string(event.Source.Tag);
            app.clearPanelTables(panelKey);
        end

        % Size changed function: Panel_SystemViewer
        function Panel_SystemViewerSizeChanged(app, event)
            axis(app.SystemViewer, 'equal')
        end

        % Value changed function: DropDown_UnitSystem
        function DropDown_UnitSystemValueChanged(app, event)
            value = app.DropDown_UnitSystem.Value;
            if value == '1'
                app.Table_UnitSystem.Data = {
                    'N/m^2 (Pa)', 'm^2', 'm^4', 'm', 'N', 'Nm', 'N/m', 'N/m' ...
                    };
            else
                app.Table_UnitSystem.Data = {
                    'kN/mm^2 (GPa)', 'mm^2', 'mm^4', 'mm', 'kN', 'kNmm', 'kN/mm', 'kN/mm' ...
                    };
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DSM Lernsoftware';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button_Beam = uibutton(app.GridLayout, 'push');
            app.Button_Beam.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Beam.Tag = 'Beam';
            app.Button_Beam.Tooltip = {'System erstellen: Knoten, Stäbe, Lager und Feder festlegen.'};
            app.Button_Beam.Text = 'Stäbe';
            app.Button_Section = uibutton(app.GridLayout, 'push');
            app.Button_Section.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Section.Tag = 'Section';
            app.Button_Section.Tooltip = {'Querschnitte erstellen.'};
            app.Button_Section.Text = 'Querschnitt';
            app.Button_Result = uibutton(app.GridLayout, 'push');
            app.Button_Result.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Result.Tag = 'Result';
            app.Button_Result.Tooltip = {'Resultate berechnen und anzeigen.'};
            app.Button_Result.Text = 'Resultate';
            app.Button_Material = uibutton(app.GridLayout, 'push');
            app.Button_Material.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Material.Tag = 'Material';
            app.Button_Material.Tooltip = {'Materialeigenschaften festlegen.'};
            app.Button_Material.Text = 'Material';
            app.Button_Actions = uibutton(app.GridLayout, 'push');
            app.Button_Actions.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftMenuPushed, true);
            app.Button_Actions.Tag = 'Actions';
            app.Button_Actions.Tooltip = {'Lasten und Zwängunen festlegen.'};
            app.Button_Actions.Text = 'Einwirkungen';
            app.Button_Inputladen = uibutton(app.GridLayout, 'push');
            app.Button_Inputladen.ButtonPushedFcn = createCallbackFcn(app, @Button_InputladenPushed, true);
            app.Button_Inputladen.Icon = fullfile(pathToMLAPP, 'icons', 'icon-mat-file-upload-light.png');
            app.Button_Inputladen.Tooltip = {'Inputfile laden'};
            app.Button_Inputladen.Text = '';
            app.Button_Inputspeichern = uibutton(app.GridLayout, 'push');
            app.Button_Inputspeichern.ButtonPushedFcn = createCallbackFcn(app, @Button_InputspeichernPushed, true);
            app.Button_Inputspeichern.Icon = fullfile(pathToMLAPP, 'icons', 'icon-mat-file-download-light.png');
            app.Button_Inputspeichern.Tooltip = {'Inputfile speichern'};
            app.Button_Inputspeichern.Text = '';
            app.Panel_SystemViewer = uipanel(app.GridLayout);
            app.Panel_SystemViewer.SizeChangedFcn = createCallbackFcn(app, @Panel_SystemViewerSizeChanged, true);
            app.GridLayout20 = uigridlayout(app.Panel_SystemViewer);
            app.SystemViewer = uiaxes(app.GridLayout20);
            title(app.SystemViewer, 'System Vorschau')
            xlabel(app.SystemViewer, 'X')
            ylabel(app.SystemViewer, 'Y')
            zlabel(app.SystemViewer, 'Z')
            app.SystemViewer.SubtitleFontWeight = 'bold';
            app.Panel_Result = uipanel(app.UIFigure);
            app.Panel_Result.Visible = 'off';
            app.GridLayout9_4 = uigridlayout(app.Panel_Result);
            app.Button_ClosePanel_3 = uibutton(app.GridLayout9_4, 'push');
            app.Button_ClosePanel_3.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close-light.png');
            app.Button_ClosePanel_3.Text = '';
            app.GewnschterOutputLabel = uilabel(app.GridLayout9_4);
            app.GewnschterOutputLabel.Text = 'Output:';
            app.DropDown_GewnschterOutput = uidropdown(app.GridLayout9_4);
            app.DropDown_GewnschterOutput.Items = {'Schnittkräfte', 'Einflusslinie', 'Auflagerreaktionen'};
            app.DropDown_GewnschterOutput.ValueChangedFcn = createCallbackFcn(app, @DropDown_GewnschterOutputValueChanged, true);
            app.DropDown_GewnschterOutput.Value = 'Schnittkräfte';
            app.Button_Calculate = uibutton(app.GridLayout9_4, 'push');
            app.Button_Calculate.ButtonPushedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.Button_Calculate.Text = 'Berechnen';
            app.SubPanel_InfluenceLine = uipanel(app.GridLayout9_4);
            app.SubPanel_InfluenceLine.Title = 'EinflussLinie';
            app.SubPanel_InfluenceLine.Visible = 'off';
            app.GridLayout14_4 = uigridlayout(app.SubPanel_InfluenceLine);
            app.TypLabel_2 = uilabel(app.GridLayout14_4);
            app.TypLabel_2.Text = 'Typ:';
            app.Dropdown_TypEL = uidropdown(app.GridLayout14_4);
            app.Dropdown_TypEL.Items = {'Normalkraft', 'Querkraft', 'Biegemoment', 'Lagerreaktion'};
            app.Dropdown_TypEL.ValueChangedFcn = createCallbackFcn(app, @Dropdown_TypELValueChanged, true);
            app.Dropdown_TypEL.Value = 'Normalkraft';
            app.Table_InfluenceLine_1 = uitable(app.GridLayout14_4);
            app.Table_InfluenceLine_1.ColumnName = {'Stab'; 'Stelle'};
            app.Table_InfluenceLine_1.RowName = {};
            app.Table_InfluenceLine_1.ColumnEditable = [true true true];
            app.Table_InfluenceLine_2 = uitable(app.GridLayout14_4);
            app.Table_InfluenceLine_2.ColumnName = {'Knoten'; 'Richtung'};
            app.Table_InfluenceLine_2.RowName = {};
            app.Table_InfluenceLine_2.ColumnEditable = [true true true];
            app.Table_InfluenceLine_2.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_InfluenceLine_2.Visible = 'off';
            app.SubPanel_StatCond = uipanel(app.GridLayout9_4);
            app.SubPanel_StatCond.Title = 'Statische Kondensation';
            app.GridLayout19 = uigridlayout(app.SubPanel_StatCond);
            app.JaNeinCheckBox = uicheckbox(app.GridLayout19);
            app.JaNeinCheckBox.ValueChangedFcn = createCallbackFcn(app, @JaNeinCheckBoxValueChanged, true);
            app.JaNeinCheckBox.Text = 'Ja/Nein';
            app.Table_StatCond = uitable(app.GridLayout19);
            app.Table_StatCond.ColumnName = {'Knoten'; 'xDOF'; 'yDOF'; 'RotationsDOF'};
            app.Table_StatCond.RowName = {};
            app.Table_StatCond.ColumnEditable = [true true true true];
            app.Table_StatCond.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_StatCond.Visible = 'off';
            app.AddRowButton_11 = uibutton(app.GridLayout19, 'push');
            app.AddRowButton_11.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_11.Tag = 'StatCondTable';
            app.AddRowButton_11.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-dark.png');
            app.AddRowButton_11.Visible = 'off';
            app.AddRowButton_11.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_11.Text = '';
            app.RemoveRowButton_11 = uibutton(app.GridLayout19, 'push');
            app.RemoveRowButton_11.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_11.Tag = 'StatCondTable';
            app.RemoveRowButton_11.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-dark.png');
            app.RemoveRowButton_11.Visible = 'off';
            app.RemoveRowButton_11.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_11.Text = '';
            app.InfoButton_11 = uibutton(app.GridLayout9_4, 'push');
            app.InfoButton_11.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_11.Tag = 'InfluenceLinePopUpPanel';
            app.InfoButton_11.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_11.Visible = 'off';
            app.InfoButton_11.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_11.Text = '';
            app.InfoButton_12 = uibutton(app.GridLayout9_4, 'push');
            app.InfoButton_12.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_12.Tag = 'StatCondPopUpPanel';
            app.InfoButton_12.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_12.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_12.Text = '';
            app.Panel_Actions = uipanel(app.UIFigure);
            app.Panel_Actions.Visible = 'off';
            app.GridLayout9 = uigridlayout(app.Panel_Actions);
            app.SubPanel_ForcedDispl = uipanel(app.GridLayout9);
            app.SubPanel_ForcedDispl.Title = 'Zwängungen';
            app.GridLayout17 = uigridlayout(app.SubPanel_ForcedDispl);
            app.Table_ForcedDispl = uitable(app.GridLayout17);
            app.Table_ForcedDispl.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.Table_ForcedDispl.RowName = {};
            app.Table_ForcedDispl.ColumnEditable = [true true true];
            app.Table_ForcedDispl.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.AddRowButton_8 = uibutton(app.GridLayout17, 'push');
            app.AddRowButton_8.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_8.Tag = 'ForcedDisplTable';
            app.AddRowButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_8.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_8.Text = '';
            app.RemoveRowButton_8 = uibutton(app.GridLayout17, 'push');
            app.RemoveRowButton_8.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_8.Tag = 'ForcedDisplTable';
            app.RemoveRowButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_8.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_8.Text = '';
            app.SubPanel_DistrLoad = uipanel(app.GridLayout9);
            app.SubPanel_DistrLoad.Title = 'Verteilte Stablasten';
            app.GridLayout16 = uigridlayout(app.SubPanel_DistrLoad);
            app.Table_DistrLoad = uitable(app.GridLayout16);
            app.Table_DistrLoad.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'; 'EndPosition'};
            app.Table_DistrLoad.RowName = {};
            app.Table_DistrLoad.ColumnEditable = [true true true true true];
            app.Table_DistrLoad.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_DistrLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_DistrLoad.Tag = 'DistrLoadTable';
            app.AddRowButton_7 = uibutton(app.GridLayout16, 'push');
            app.AddRowButton_7.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_7.Tag = 'DistrLoadTable';
            app.AddRowButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_7.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_7.Text = '';
            app.RemoveRowButton_7 = uibutton(app.GridLayout16, 'push');
            app.RemoveRowButton_7.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_7.Tag = 'DistrLoadTable';
            app.RemoveRowButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_7.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_7.Text = '';
            app.SubPanel_PointLoad = uipanel(app.GridLayout9);
            app.SubPanel_PointLoad.Title = 'Konz. Stablasten';
            app.GridLayout15 = uigridlayout(app.SubPanel_PointLoad);
            app.Table_PointLoad = uitable(app.GridLayout15);
            app.Table_PointLoad.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'};
            app.Table_PointLoad.RowName = {};
            app.Table_PointLoad.ColumnEditable = [true true true true];
            app.Table_PointLoad.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_PointLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_PointLoad.Tag = 'PointLoadTable';
            app.AddRowButton_6 = uibutton(app.GridLayout15, 'push');
            app.AddRowButton_6.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_6.Tag = 'PointLoadTable';
            app.AddRowButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_6.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_6.Text = '';
            app.RemoveRowButton_6 = uibutton(app.GridLayout15, 'push');
            app.RemoveRowButton_6.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_6.Tag = 'PointLoadTable';
            app.RemoveRowButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_6.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_6.Text = '';
            app.SubPanel_NodalLoad = uipanel(app.GridLayout9);
            app.SubPanel_NodalLoad.Title = 'Knoten Lasten';
            app.GridLayout14 = uigridlayout(app.SubPanel_NodalLoad);
            app.Table_NodalLoad = uitable(app.GridLayout14);
            app.Table_NodalLoad.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.Table_NodalLoad.RowName = {};
            app.Table_NodalLoad.ColumnEditable = [true true true];
            app.Table_NodalLoad.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_NodalLoad.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_NodalLoad.Tag = 'NodalLoadTable';
            app.AddRowButton_5 = uibutton(app.GridLayout14, 'push');
            app.AddRowButton_5.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_5.Tag = 'NodalLoadTable';
            app.AddRowButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_5.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_5.Text = '';
            app.RemoveRowButton_5 = uibutton(app.GridLayout14, 'push');
            app.RemoveRowButton_5.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_5.Tag = 'NodalLoadTable';
            app.RemoveRowButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_5.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_5.Text = '';
            app.InfoButton_8 = uibutton(app.GridLayout9, 'push');
            app.InfoButton_8.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_8.Tag = 'ForcedDisplPopUpPanel';
            app.InfoButton_8.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_8.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_8.Text = '';
            app.InfoButton_7 = uibutton(app.GridLayout9, 'push');
            app.InfoButton_7.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_7.Tag = 'DistrLoadPopUpPanel';
            app.InfoButton_7.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_7.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_7.Text = '';
            app.InfoButton_6 = uibutton(app.GridLayout9, 'push');
            app.InfoButton_6.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_6.Tag = 'PointLoadPopUpPanel';
            app.InfoButton_6.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_6.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_6.Text = '';
            app.InfoButton_5 = uibutton(app.GridLayout9, 'push');
            app.InfoButton_5.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_5.Tag = 'NodalLoadPopUpPanel';
            app.InfoButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_5.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_5.Text = '';
            app.Button_ClosePanel_2 = uibutton(app.GridLayout9, 'push');
            app.Button_ClosePanel_2.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close-light.png');
            app.Button_ClosePanel_2.Text = '';
            app.Button_ResetTables_2 = uibutton(app.GridLayout9, 'push');
            app.Button_ResetTables_2.ButtonPushedFcn = createCallbackFcn(app, @Button_resetTablesPushed, true);
            app.Button_ResetTables_2.Tag = 'Actions';
            app.Button_ResetTables_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-trash-o-light.png');
            app.Button_ResetTables_2.Tooltip = {'Alle Tabellen zürucksetzen'};
            app.Button_ResetTables_2.Text = '';
            app.Panel_Beam = uipanel(app.UIFigure);
            app.Panel_Beam.Visible = 'off';
            app.GridLayout8_2 = uigridlayout(app.Panel_Beam);
            app.SubPanel_Spring = uipanel(app.GridLayout8_2);
            app.SubPanel_Spring.Title = 'Feder';
            app.GridLayout13 = uigridlayout(app.SubPanel_Spring);
            app.Table_Spring = uitable(app.GridLayout13);
            app.Table_Spring.ColumnName = {'Knoten'; 'Feder'; 'Betrag'};
            app.Table_Spring.RowName = {};
            app.Table_Spring.ColumnEditable = [true true true];
            app.Table_Spring.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_Spring.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Spring.Tag = 'SpringTable';
            app.AddRowButton_4 = uibutton(app.GridLayout13, 'push');
            app.AddRowButton_4.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_4.Tag = 'SpringTable';
            app.AddRowButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_4.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_4.Text = '';
            app.RemoveRowButton_4 = uibutton(app.GridLayout13, 'push');
            app.RemoveRowButton_4.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_4.Tag = 'SpringTable';
            app.RemoveRowButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_4.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_4.Text = '';
            app.SubPanel_Support = uipanel(app.GridLayout8_2);
            app.SubPanel_Support.Title = 'Lager';
            app.GridLayout12 = uigridlayout(app.SubPanel_Support);
            app.Table_Support = uitable(app.GridLayout12);
            app.Table_Support.ColumnName = {'Knoten'; 'Lagerung'};
            app.Table_Support.RowName = {};
            app.Table_Support.ColumnEditable = [true true];
            app.Table_Support.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_Support.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Support.Tag = 'SupportTable';
            app.AddRowButton_3 = uibutton(app.GridLayout12, 'push');
            app.AddRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_3.Tag = 'SupportTable';
            app.AddRowButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_3.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_3.Text = '';
            app.RemoveRowButton_3 = uibutton(app.GridLayout12, 'push');
            app.RemoveRowButton_3.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_3.Tag = 'SupportTable';
            app.RemoveRowButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_3.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_3.Text = '';
            app.SubPanel_Beam = uipanel(app.GridLayout8_2);
            app.SubPanel_Beam.Title = 'Stäbe';
            app.GridLayout11 = uigridlayout(app.SubPanel_Beam);
            app.Table_Beam = uitable(app.GridLayout11);
            app.Table_Beam.ColumnName = {'StartKnoten'; 'EndKnoten'; 'Querschnitt'; 'GelenkStabAnfang'; 'GelenkStabende'};
            app.Table_Beam.ColumnEditable = [true true true true true];
            app.Table_Beam.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_Beam.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Beam.Tag = 'BeamTable';
            app.AddRowButton_2 = uibutton(app.GridLayout11, 'push');
            app.AddRowButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_2.Tag = 'BeamTable';
            app.AddRowButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_2.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_2.Text = '';
            app.RemoveRowButton_2 = uibutton(app.GridLayout11, 'push');
            app.RemoveRowButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_2.Tag = 'BeamTable';
            app.RemoveRowButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_2.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_2.Text = '';
            app.SubPanel_Node = uipanel(app.GridLayout8_2);
            app.SubPanel_Node.Title = 'Knoten';
            app.GridLayout10 = uigridlayout(app.SubPanel_Node);
            app.Table_Node = uitable(app.GridLayout10);
            app.Table_Node.ColumnName = {'xPos'; 'yPos'};
            app.Table_Node.ColumnEditable = [true true];
            app.Table_Node.CellEditCallback = createCallbackFcn(app, @Table_CellEdit, true);
            app.Table_Node.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Node.Tag = 'NodeTable';
            app.AddRowButton_1 = uibutton(app.GridLayout10, 'push');
            app.AddRowButton_1.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_1.Tag = 'NodeTable';
            app.AddRowButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_1.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_1.Text = '';
            app.RemoveRowButton_1 = uibutton(app.GridLayout10, 'push');
            app.RemoveRowButton_1.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_1.Tag = 'NodeTable';
            app.RemoveRowButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_1.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_1.Text = '';
            app.InfoButton_4 = uibutton(app.GridLayout8_2, 'push');
            app.InfoButton_4.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_4.Tag = 'SpringPopUpPanel';
            app.InfoButton_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_4.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_4.Text = '';
            app.InfoButton_3 = uibutton(app.GridLayout8_2, 'push');
            app.InfoButton_3.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_3.Tag = 'SupportPopUpPanel';
            app.InfoButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_3.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_3.Text = '';
            app.InfoButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_2.Tag = 'BeamPopUpPanel';
            app.InfoButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_2.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_2.Text = '';
            app.InfoButton_1 = uibutton(app.GridLayout8_2, 'push');
            app.InfoButton_1.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_1.Tag = 'NodePopUpPanel';
            app.InfoButton_1.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_1.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_1.Text = '';
            app.Button_ClosePanel_1 = uibutton(app.GridLayout8_2, 'push');
            app.Button_ClosePanel_1.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel_1.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close-light.png');
            app.Button_ClosePanel_1.Text = '';
            app.Button_ResetTables_1 = uibutton(app.GridLayout8_2, 'push');
            app.Button_ResetTables_1.ButtonPushedFcn = createCallbackFcn(app, @Button_resetTablesPushed, true);
            app.Button_ResetTables_1.Tag = 'Beam';
            app.Button_ResetTables_1.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-trash-o-light.png');
            app.Button_ResetTables_1.Tooltip = {'Alle Tabellen zürucksetzen'};
            app.Button_ResetTables_1.Text = '';
            app.Panel_Section = uipanel(app.UIFigure);
            app.Panel_Section.Visible = 'off';
            app.GridLayout9_3 = uigridlayout(app.Panel_Section);
            app.SubPanel_Section = uipanel(app.GridLayout9_3);
            app.SubPanel_Section.Title = 'Querschnitt';
            app.GridLayout14_3 = uigridlayout(app.SubPanel_Section);
            app.AddRowButton_10 = uibutton(app.GridLayout14_3, 'push');
            app.AddRowButton_10.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_10.Tag = 'SectionTable';
            app.AddRowButton_10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_10.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_10.Text = '';
            app.RemoveRowButton_10 = uibutton(app.GridLayout14_3, 'push');
            app.RemoveRowButton_10.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_10.Tag = 'SectionTable';
            app.RemoveRowButton_10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_10.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_10.Text = '';
            app.Table_Section = uitable(app.GridLayout14_3);
            app.Table_Section.ColumnName = {'Name'; 'Material'; 'Flaeche'; 'Traegheitsmoment'; 'dehnstarr'; 'biegesteif'};
            app.Table_Section.RowName = {};
            app.Table_Section.ColumnEditable = [true true true true true true];
            app.Table_Section.CellEditCallback = createCallbackFcn(app, @Table_UpdateDropdown, true);
            app.Table_Section.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Section.Tag = 'SectionTable';
            app.InfoButton_10 = uibutton(app.GridLayout9_3, 'push');
            app.InfoButton_10.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_10.Tag = 'SectionPopUpPanel';
            app.InfoButton_10.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_10.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_10.Text = '';
            app.Button_ClosePanel_4 = uibutton(app.GridLayout9_3, 'push');
            app.Button_ClosePanel_4.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close-light.png');
            app.Button_ClosePanel_4.Text = '';
            app.Button_ResetTables_4 = uibutton(app.GridLayout9_3, 'push');
            app.Button_ResetTables_4.ButtonPushedFcn = createCallbackFcn(app, @Button_resetTablesPushed, true);
            app.Button_ResetTables_4.Tag = 'Section';
            app.Button_ResetTables_4.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-trash-o-light.png');
            app.Button_ResetTables_4.Tooltip = {'Alle Tabellen zürucksetzen'};
            app.Button_ResetTables_4.Text = '';
            app.Panel_Material = uipanel(app.UIFigure);
            app.Panel_Material.Visible = 'off';
            app.GridLayout9_2 = uigridlayout(app.Panel_Material);
            app.SubPanel_Material = uipanel(app.GridLayout9_2);
            app.SubPanel_Material.Title = 'Material';
            app.GridLayout14_2 = uigridlayout(app.SubPanel_Material);
            app.Table_Material = uitable(app.GridLayout14_2);
            app.Table_Material.ColumnName = {'Name'; 'EModul'};
            app.Table_Material.RowName = {};
            app.Table_Material.ColumnEditable = [true true];
            app.Table_Material.CellEditCallback = createCallbackFcn(app, @Table_UpdateDropdown, true);
            app.Table_Material.CellSelectionCallback = createCallbackFcn(app, @Table_GetRowCellSelection, true);
            app.Table_Material.Tag = 'MaterialTable';
            app.AddRowButton_9 = uibutton(app.GridLayout14_2, 'push');
            app.AddRowButton_9.ButtonPushedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.AddRowButton_9.Tag = 'MaterialTable';
            app.AddRowButton_9.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-plus-light.png');
            app.AddRowButton_9.Tooltip = {'Zeile hinzufügen.'};
            app.AddRowButton_9.Text = '';
            app.RemoveRowButton_9 = uibutton(app.GridLayout14_2, 'push');
            app.RemoveRowButton_9.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.RemoveRowButton_9.Tag = 'MaterialTable';
            app.RemoveRowButton_9.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-minus-light.png');
            app.RemoveRowButton_9.Tooltip = {'Ausgewählte Zeile(n) entfernen.'};
            app.RemoveRowButton_9.Text = '';
            app.InfoButton_9 = uibutton(app.GridLayout9_2, 'push');
            app.InfoButton_9.ButtonPushedFcn = createCallbackFcn(app, @Button_InfoPushed, true);
            app.InfoButton_9.Tag = 'MaterialPopUpPanel';
            app.InfoButton_9.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-info-light.png');
            app.InfoButton_9.Tooltip = {'Tabelleninfo anzeigen.'};
            app.InfoButton_9.Text = '';
            app.Button_ClosePanel_5 = uibutton(app.GridLayout9_2, 'push');
            app.Button_ClosePanel_5.ButtonPushedFcn = createCallbackFcn(app, @Button_BackPushed, true);
            app.Button_ClosePanel_5.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close-light.png');
            app.Button_ClosePanel_5.Text = '';
            app.Button_ResetTables_3 = uibutton(app.GridLayout9_2, 'push');
            app.Button_ResetTables_3.ButtonPushedFcn = createCallbackFcn(app, @Button_resetTablesPushed, true);
            app.Button_ResetTables_3.Tag = 'Material';
            app.Button_ResetTables_3.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-trash-o-light.png');
            app.Button_ResetTables_3.Tooltip = {'Alle Tabellen zürucksetzen'};
            app.Button_ResetTables_3.Text = '';
            app.PopUpPanel_Material = uipanel(app.UIFigure);
            app.PopUpPanel_Material.Visible = 'off';
            app.GridLayout18 = uigridlayout(app.PopUpPanel_Material);
            app.NameLabel = uilabel(app.GridLayout18);
            app.NameLabel.Text = 'Name:';
            app.NameTextArea = uitextarea(app.GridLayout18);
            app.NameTextArea.Value = {'Bezeichnung des Materials.'};
            app.EModulLabel = uilabel(app.GridLayout18);
            app.EModulLabel.Text = 'EModul:';
            app.EModulTextArea = uitextarea(app.GridLayout18);
            app.EModulTextArea.Value = {'Elastizitätsmodul des Materials.'};
            app.PopUpPanel_Node = uipanel(app.UIFigure);
            app.PopUpPanel_Node.Visible = 'off';
            app.GridLayout18_2 = uigridlayout(app.PopUpPanel_Node);
            app.xPosLabel = uilabel(app.GridLayout18_2);
            app.xPosLabel.Text = 'xPos:';
            app.xPosTextArea = uitextarea(app.GridLayout18_2);
            app.xPosTextArea.Value = {'Gibt die x-Koordinate des Knotens an. Die Werte entsprechen den Positionen im globalen Koordinatensystem. Einheiten sind frei wählbar, solange sie konsistent bleiben.'};
            app.yPosLabel = uilabel(app.GridLayout18_2);
            app.yPosLabel.Text = 'yPos:';
            app.yPosTextArea = uitextarea(app.GridLayout18_2);
            app.yPosTextArea.Value = {'Gibt die y-Koordinate des Knotens an. Werte entsprechen der globalen y-Position des Knotens.'};
            app.PopUpPanel_Beam = uipanel(app.UIFigure);
            app.PopUpPanel_Beam.Visible = 'off';
            app.GridLayout18_3 = uigridlayout(app.PopUpPanel_Beam);
            app.EndKnotenLabel = uilabel(app.GridLayout18_3);
            app.EndKnotenLabel.Text = 'EndKnoten:';
            app.EndKnotenTextArea = uitextarea(app.GridLayout18_3);
            app.EndKnotenTextArea.Value = {'Der Knoten, an dem der Stab endet.'};
            app.StartKnotenLabel = uilabel(app.GridLayout18_3);
            app.StartKnotenLabel.Text = 'StartKnoten:';
            app.StartKnotenTextArea = uitextarea(app.GridLayout18_3);
            app.StartKnotenTextArea.Value = {'Der Knoten, an dem der Stab beginnt. Die Orientierung des Stabes (wichtig für lokale Lasten) hängt von der Wahl von Start- und Endknoten ab.'};
            app.QuerschnittLabel = uilabel(app.GridLayout18_3);
            app.QuerschnittLabel.Text = 'Querschnitt:';
            app.QuerschnittTextArea = uitextarea(app.GridLayout18_3);
            app.QuerschnittTextArea.Value = {'Welchen Querschnitt der Stab verwendet. Querschnitte werden separat in der Tabelle Querschnitt definiert.'};
            app.GelenkStabAnfangLabel = uilabel(app.GridLayout18_3);
            app.GelenkStabAnfangLabel.Text = 'GelenkStabAnfang:';
            app.GelenkStabAnfangTextArea = uitextarea(app.GridLayout18_3);
            app.GelenkStabAnfangTextArea.Value = {'Gelenktyp am Stabanfang.'};
            app.GelenkStabendeLabel = uilabel(app.GridLayout18_3);
            app.GelenkStabendeLabel.Text = 'GelenkStabende:';
            app.GelenkStabendeTextArea = uitextarea(app.GridLayout18_3);
            app.GelenkStabendeTextArea.Value = {'Gelenktyp am Stabende.'};
            app.Image = uiimage(app.GridLayout18_3);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'GelenkTypen.png');
            app.PopUpPanel_Support = uipanel(app.UIFigure);
            app.PopUpPanel_Support.Visible = 'off';
            app.GridLayout18_4 = uigridlayout(app.PopUpPanel_Support);
            app.KnotenLabel = uilabel(app.GridLayout18_4);
            app.KnotenLabel.Text = 'Knoten:';
            app.KnotenTextArea = uitextarea(app.GridLayout18_4);
            app.KnotenTextArea.Value = {'Der Knoten, an dem ein Lager definiert wird.'};
            app.LagerungTextAreaLabel = uilabel(app.GridLayout18_4);
            app.LagerungTextAreaLabel.Text = 'Lagerung:';
            app.LagerungTextArea = uitextarea(app.GridLayout18_4);
            app.LagerungTextArea.Value = {'Art des Lagers.'};
            app.Image2 = uiimage(app.GridLayout18_4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'LagerTypen.png');
            app.PopUpPanel_Spring = uipanel(app.UIFigure);
            app.PopUpPanel_Spring.Visible = 'off';
            app.GridLayout18_5 = uigridlayout(app.PopUpPanel_Spring);
            app.KnotenTextArea_2Label = uilabel(app.GridLayout18_5);
            app.KnotenTextArea_2Label.Text = 'Knoten:';
            app.KnotenTextArea_2 = uitextarea(app.GridLayout18_5);
            app.KnotenTextArea_2.Value = {'Der Knoten, an dem die Feder angreift.'};
            app.FederTextAreaLabel = uilabel(app.GridLayout18_5);
            app.FederTextAreaLabel.Text = 'Feder:';
            app.FederTextArea = uitextarea(app.GridLayout18_5);
            app.FederTextArea.Value = {'Richtung der Feder.'};
            app.BetragTextAreaLabel = uilabel(app.GridLayout18_5);
            app.BetragTextAreaLabel.Text = 'Betrag:';
            app.BetragTextArea = uitextarea(app.GridLayout18_5);
            app.BetragTextArea.Value = {'Die Steifigkeit der Feder.'};
            app.PopUpPanel_NodalLoad = uipanel(app.UIFigure);
            app.PopUpPanel_NodalLoad.Visible = 'off';
            app.GridLayout18_6 = uigridlayout(app.PopUpPanel_NodalLoad);
            app.KnotenTextArea_5Label = uilabel(app.GridLayout18_6);
            app.KnotenTextArea_5Label.Text = 'Knoten:';
            app.KnotenTextArea_5 = uitextarea(app.GridLayout18_6);
            app.KnotenTextArea_5.Value = {'Der Knoten, auf den die Last wirkt.'};
            app.RichtungTextAreaLabel = uilabel(app.GridLayout18_6);
            app.RichtungTextAreaLabel.Text = 'Richtung:';
            app.RichtungTextArea = uitextarea(app.GridLayout18_6);
            app.RichtungTextArea.Value = {'Richtung der Last im globalen Koordinatensystem.'};
            app.WertTextAreaLabel = uilabel(app.GridLayout18_6);
            app.WertTextAreaLabel.Text = 'Wert:';
            app.WertTextArea = uitextarea(app.GridLayout18_6);
            app.WertTextArea.Value = {'Lastbetrag. Das Vorzeichen definiert die Richtung (z. B. negative vertikale Last nach unten).'};
            app.PopUpPanel_PointLoad = uipanel(app.UIFigure);
            app.PopUpPanel_PointLoad.Visible = 'off';
            app.GridLayout18_7 = uigridlayout(app.PopUpPanel_PointLoad);
            app.RichtungLabel = uilabel(app.GridLayout18_7);
            app.RichtungLabel.Text = 'Richtung:';
            app.RichtungTextArea_2 = uitextarea(app.GridLayout18_7);
            app.RichtungTextArea_2.Value = {'Richtung der Last im lokalen Koordinatensystem des Stabes.'};
            app.StabTextAreaLabel = uilabel(app.GridLayout18_7);
            app.StabTextAreaLabel.Text = 'Stab:';
            app.StabTextArea = uitextarea(app.GridLayout18_7);
            app.StabTextArea.Value = {'Stab, auf den die konzentrierte Last wirkt.'};
            app.WertTextArea_2Label = uilabel(app.GridLayout18_7);
            app.WertTextArea_2Label.Text = 'Wert:';
            app.WertTextArea_2 = uitextarea(app.GridLayout18_7);
            app.WertTextArea_2.Value = {'Lastbetrag, Vorzeichen beachten. Die Orientierung folgt der Richtung des Start- zu Endknotens.'};
            app.StartpositionLabel = uilabel(app.GridLayout18_7);
            app.StartpositionLabel.Text = 'StartPosition:';
            app.StartPositionTextArea_2 = uitextarea(app.GridLayout18_7);
            app.StartPositionTextArea_2.Value = {'Position der Last entlang des Stabes relativ zur Stablänge L.'; 'Beispiel: 0 = am Anfang, 1 = am Ende.'};
            app.PopUpPanel_ForcedDispl = uipanel(app.UIFigure);
            app.PopUpPanel_ForcedDispl.Visible = 'off';
            app.GridLayout18_9 = uigridlayout(app.PopUpPanel_ForcedDispl);
            app.KnotenTextArea_4Label = uilabel(app.GridLayout18_9);
            app.KnotenTextArea_4Label.Text = 'Knoten:';
            app.KnotenTextArea_4 = uitextarea(app.GridLayout18_9);
            app.KnotenTextArea_4.Value = {'Der Knoten mit vorgeschriebener Verschiebung.'};
            app.RichtungTextArea_4Label = uilabel(app.GridLayout18_9);
            app.RichtungTextArea_4Label.Text = 'Richtung:';
            app.RichtungTextArea_4 = uitextarea(app.GridLayout18_9);
            app.RichtungTextArea_4.Value = {'Richtung der Verschiebung im globalen Koordinatensystem'};
            app.WertLabel = uilabel(app.GridLayout18_9);
            app.WertLabel.Text = 'Wert:';
            app.WertTextArea_4 = uitextarea(app.GridLayout18_9);
            app.WertTextArea_4.Value = {'Verschiebungs- oder Verdrehwert. Vorzeichen beschreibt die Richtung.'};
            app.PopUpPanel_DistrLoad = uipanel(app.UIFigure);
            app.PopUpPanel_DistrLoad.Visible = 'off';
            app.GridLayout18_10 = uigridlayout(app.PopUpPanel_DistrLoad);
            app.RichtungTextArea_3Label = uilabel(app.GridLayout18_10);
            app.RichtungTextArea_3Label.Text = 'Richtung:';
            app.RichtungTextArea_3 = uitextarea(app.GridLayout18_10);
            app.RichtungTextArea_3.Value = {'Richtung der Last im lokalen Koordinatensystem.'};
            app.StabTextArea_2Label = uilabel(app.GridLayout18_10);
            app.StabTextArea_2Label.Text = 'Stab:';
            app.StabTextArea_2 = uitextarea(app.GridLayout18_10);
            app.StabTextArea_2.Value = {'Stab, auf den die verteilte Last wirkt.'};
            app.WertTextArea_3Label = uilabel(app.GridLayout18_10);
            app.WertTextArea_3Label.Text = 'Wert:';
            app.WertTextArea_3 = uitextarea(app.GridLayout18_10);
            app.WertTextArea_3.Value = {'Wert der verteilten Last (pro Längeneinheit). Vorzeichen beachten.'};
            app.StartPositionTextAreaLabel = uilabel(app.GridLayout18_10);
            app.StartPositionTextAreaLabel.Text = 'StartPosition:';
            app.StartPositionTextArea = uitextarea(app.GridLayout18_10);
            app.StartPositionTextArea.Value = {'Startpunkt der Last in Abhängigkeit von L (0 = Stabanfang).'};
            app.EndpositionTextAreaLabel = uilabel(app.GridLayout18_10);
            app.EndpositionTextAreaLabel.Text = 'Endposition:';
            app.EndpositionTextArea = uitextarea(app.GridLayout18_10);
            app.EndpositionTextArea.Value = {'Endpunkt der Last in Abhängigkeit von L (1 = Stabende).'};
            app.PopUpPanel_Section = uipanel(app.UIFigure);
            app.PopUpPanel_Section.Visible = 'off';
            app.GridLayout18_11 = uigridlayout(app.PopUpPanel_Section);
            app.NameTextArea_2Label = uilabel(app.GridLayout18_11);
            app.NameTextArea_2Label.Text = 'Name:';
            app.NameTextArea_2 = uitextarea(app.GridLayout18_11);
            app.NameTextArea_2.Value = {'Bezeichnung des Querschnitts.'};
            app.MaterialLabel = uilabel(app.GridLayout18_11);
            app.MaterialLabel.Text = 'Material:';
            app.MaterialTextArea = uitextarea(app.GridLayout18_11);
            app.MaterialTextArea.Value = {'Welches Material dem Querschnitt zugeordnet ist.'};
            app.FlcheTextAreaLabel = uilabel(app.GridLayout18_11);
            app.FlcheTextAreaLabel.Text = 'Fläche:';
            app.FlcheTextArea = uitextarea(app.GridLayout18_11);
            app.FlcheTextArea.Value = {'Querschnittsfläche A des Stabes. Sie wird für die Berechnung der Normalkraftsteifigkeit EA verwendet.'};
            app.TrgheitsmomentTextAreaLabel = uilabel(app.GridLayout18_11);
            app.TrgheitsmomentTextAreaLabel.Text = 'Trägheitsmoment:';
            app.TrgheitsmomentTextArea = uitextarea(app.GridLayout18_11);
            app.TrgheitsmomentTextArea.Value = {'Flächenträgheitsmoment I des Querschnitts um die relevante Biegeachse. Es wird für die Berechnung der Biegesteifigkeit '; 'EI verwendet.'};
            app.DehnstarrTextAreaLabel = uilabel(app.GridLayout18_11);
            app.DehnstarrTextAreaLabel.Text = 'Dehnstarr:';
            app.DehnstarrTextArea = uitextarea(app.GridLayout18_11);
            app.DehnstarrTextArea.Value = {'Option, um den Stab in Dehnrichtung praktisch unverschieblich zu modellieren. Entspricht einem sehr grossen A (dehnstarrer Stab).'};
            app.BiegesteifTextAreaLabel = uilabel(app.GridLayout18_11);
            app.BiegesteifTextAreaLabel.Text = 'Biegesteif:';
            app.BiegesteifTextArea = uitextarea(app.GridLayout18_11);
            app.BiegesteifTextArea.Value = {'Option, um den Stab in Biegung praktisch unverschieblich zu modellieren. Entspricht einem sehr grossen I (biegestarrer Stab).'};
            app.PopUpPanel_InfluenceLine = uipanel(app.UIFigure);
            app.PopUpPanel_InfluenceLine.Visible = 'off';
            app.GridLayout18_12 = uigridlayout(app.PopUpPanel_InfluenceLine);
            app.TypLabel = uilabel(app.GridLayout18_12);
            app.TypLabel.Text = 'Typ:';
            app.TypTextArea = uitextarea(app.GridLayout18_12);
            app.TypTextArea.Value = {'Art der Einflusslinie.'};
            app.StabLabel = uilabel(app.GridLayout18_12);
            app.StabLabel.Text = 'Stab:';
            app.StabTextArea_3 = uitextarea(app.GridLayout18_12);
            app.StabTextArea_3.Value = {'Stab, für den die Einflusslinie einer Schnittgrösse berechnet wird (nur Typ 1–3).'};
            app.StelleTextAreaLabel = uilabel(app.GridLayout18_12);
            app.StelleTextAreaLabel.Text = 'Stelle:';
            app.StelleTextArea = uitextarea(app.GridLayout18_12);
            app.StelleTextArea.Value = {'Position entlang des Stabes relativ zu L (0 = Anfang, 1 = Ende).'};
            app.KnotenTextArea_6Label = uilabel(app.GridLayout18_12);
            app.KnotenTextArea_6Label.Text = 'Knoten:';
            app.KnotenTextArea_6 = uitextarea(app.GridLayout18_12);
            app.KnotenTextArea_6.Value = {'Knoten, für den die Einflusslinie einer Lagerreaktion berechnet wird (nur Typ 4).'};
            app.RichtungTextArea_5Label = uilabel(app.GridLayout18_12);
            app.RichtungTextArea_5Label.Text = 'Richtung:';
            app.RichtungTextArea_5 = uitextarea(app.GridLayout18_12);
            app.RichtungTextArea_5.Value = {'Richtung auf welche sich die Lagerreaktion bezieht.'};
            app.PopUpPanel_StatCond = uipanel(app.UIFigure);
            app.PopUpPanel_StatCond.Visible = 'off';
            app.GridLayout18_13 = uigridlayout(app.PopUpPanel_StatCond);
            app.KnotenTextArea_8Label = uilabel(app.GridLayout18_13);
            app.KnotenTextArea_8Label.Text = 'Knoten:';
            app.KnotenTextArea_8 = uitextarea(app.GridLayout18_13);
            app.KnotenTextArea_8.Value = {'Knoten an dem Statische Kondensation stattfinden sollte.'};
            app.xDOFTextAreaLabel = uilabel(app.GridLayout18_13);
            app.xDOFTextAreaLabel.Text = 'x-DOF:';
            app.xDOFTextArea = uitextarea(app.GridLayout18_13);
            app.xDOFTextArea.Value = {'Lokaler x-DOF kondensieren.'};
            app.yDOFTextAreaLabel = uilabel(app.GridLayout18_13);
            app.yDOFTextAreaLabel.Text = 'y-DOF:';
            app.yDOFTextArea = uitextarea(app.GridLayout18_13);
            app.yDOFTextArea.Value = {'Lokaler y-DOF kondensieren.'};
            app.RotationsDOFTextAreaLabel = uilabel(app.GridLayout18_13);
            app.RotationsDOFTextAreaLabel.Text = 'Rotations-DOF';
            app.RotationsDOFTextArea = uitextarea(app.GridLayout18_13);
            app.RotationsDOFTextArea.Value = {'Lokaler Rotations-DOF kondensieren.'};
            app.Panel_UnitInfo = uipanel(app.UIFigure);
            app.GridLayout22 = uigridlayout(app.Panel_UnitInfo);
            app.TextArea_Units = uitextarea(app.GridLayout22);
            app.TextArea_Units.Value = {'Die Software führt keine Einheitenumrechnung durch. Bitte verwenden Sie ein konsistentes Einheitensystem für alle Eingaben.'; ''};
            app.Table_UnitSystem = uitable(app.GridLayout22);
            app.Table_UnitSystem.ColumnName = {'E'; 'A'; 'I'; 'Länge'; 'Kraft'; 'Moment'; 'Linienlast'; 'Federsteifigkeit'};
            app.Table_UnitSystem.RowName = {};
            app.DropDown_UnitSystem = uidropdown(app.GridLayout22);
            app.DropDown_UnitSystem.Items = {'SI: m, N, Pa', 'Ingenieur: mm, kN, (GPa)'};
            app.DropDown_UnitSystem.ItemsData = {'1', '2'};
            app.DropDown_UnitSystem.ValueChangedFcn = createCallbackFcn(app, @DropDown_UnitSystemValueChanged, true);
            app.DropDown_UnitSystem.Value = '1';
            app.ContextMenu_MaterialTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Material = uimenu(app.ContextMenu_MaterialTable);
            app.HinzufgenMenu_Material.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Material.Text = 'Hinzufügen';
            app.HinzufgenMenu_Material.Tag = 'MaterialTable';
            app.LschenMenu_Material = uimenu(app.ContextMenu_MaterialTable);
            app.LschenMenu_Material.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Material.Text = 'Löschen';
            app.LschenMenu_Material.Tag = 'MaterialTable';
            % Assign app.ContextMenu_MaterialTable
            app.Table_Material.ContextMenu = app.ContextMenu_MaterialTable;
            app.ContextMenu_SectionTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Section = uimenu(app.ContextMenu_SectionTable);
            app.HinzufgenMenu_Section.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Section.Text = 'Hinzufügen';
            app.HinzufgenMenu_Section.Tag = 'SectionTable';
            app.LschenMenu_Section = uimenu(app.ContextMenu_SectionTable);
            app.LschenMenu_Section.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Section.Text = 'Löschen';
            app.LschenMenu_Section.Tag = 'SectionTable';
            % Assign app.ContextMenu_SectionTable

            app.Table_Section.ContextMenu = app.ContextMenu_SectionTable;
            app.ContextMenu_NodeTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Node = uimenu(app.ContextMenu_NodeTable);
            app.HinzufgenMenu_Node.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Node.Text = 'Hinzufügen';
            app.HinzufgenMenu_Node.Tag = 'NodeTable';
            app.LschenMenu_Node = uimenu(app.ContextMenu_NodeTable);
            app.LschenMenu_Node.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Node.Text = 'Löschen';
            app.LschenMenu_Node.Tag = 'NodeTable';
            % Assign app.ContextMenu_NodeTable
            app.Table_Node.ContextMenu = app.ContextMenu_NodeTable;
            app.ContextMenu_BeamTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Beam = uimenu(app.ContextMenu_BeamTable);
            app.HinzufgenMenu_Beam.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Beam.Text = 'Hinzufügen';
            app.HinzufgenMenu_Beam.Tag = 'BeamTable';
            app.LschenMenu_Beam = uimenu(app.ContextMenu_BeamTable);
            app.LschenMenu_Beam.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Beam.Text = 'Löschen';
            app.LschenMenu_Beam.Tag = 'BeamTable';
            % Assign app.ContextMenu_BeamTable
            app.Table_Beam.ContextMenu = app.ContextMenu_BeamTable;
            app.ContextMenu_SupportTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Support = uimenu(app.ContextMenu_SupportTable);
            app.HinzufgenMenu_Support.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Support.Text = 'Hinzufügen';
            app.HinzufgenMenu_Support.Tag = 'SupportTable';
            app.LschenMenu_Support = uimenu(app.ContextMenu_SupportTable);
            app.LschenMenu_Support.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Support.Text = 'Löschen';
            app.LschenMenu_Support.Tag = 'SupportTable';
            % Assign app.ContextMenu_SupportTable
            app.Table_Support.ContextMenu = app.ContextMenu_SupportTable;
            app.ContextMenu_SpringTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_Spring = uimenu(app.ContextMenu_SpringTable);
            app.HinzufgenMenu_Spring.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_Spring.Text = 'Hinzufügen';
            app.HinzufgenMenu_Spring.Tag = 'SpringTable';
            app.LschenMenu_Spring = uimenu(app.ContextMenu_SpringTable);
            app.LschenMenu_Spring.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_Spring.Text = 'Löschen';
            app.LschenMenu_Spring.Tag = 'SpringTable';
            % Assign app.ContextMenu_SpringTable
            app.Table_Spring.ContextMenu = app.ContextMenu_SpringTable;
            app.ContextMenu_NodalLoadTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_NodalLoad = uimenu(app.ContextMenu_NodalLoadTable);
            app.HinzufgenMenu_NodalLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_NodalLoad.Text = 'Hinzufügen';
            app.HinzufgenMenu_NodalLoad.Tag = 'NodalLoadTable';
            app.LschenMenu_NodalLoad = uimenu(app.ContextMenu_NodalLoadTable);
            app.LschenMenu_NodalLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_NodalLoad.Text = 'Löschen';
            app.LschenMenu_NodalLoad.Tag = 'NodalLoadTable';
            % Assign app.ContextMenu_NodalLoadTable
            app.Table_NodalLoad.ContextMenu = app.ContextMenu_NodalLoadTable;
            app.ContextMenu_PointLoadTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_PointLoad = uimenu(app.ContextMenu_PointLoadTable);
            app.HinzufgenMenu_PointLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_PointLoad.Text = 'Hinzufügen';
            app.HinzufgenMenu_PointLoad.Tag = 'PointLoadTable';
            app.LschenMenu_PointLoad = uimenu(app.ContextMenu_PointLoadTable);
            app.LschenMenu_PointLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_PointLoad.Text = 'Löschen';
            app.LschenMenu_PointLoad.Tag = 'PointLoadTable';
            % Assign app.ContextMenu_PointLoadTable
            app.Table_PointLoad.ContextMenu = app.ContextMenu_PointLoadTable;
            app.ContextMenu_DistrLoadTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_DistrLoad = uimenu(app.ContextMenu_DistrLoadTable);
            app.HinzufgenMenu_DistrLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_DistrLoad.Text = 'Hinzufügen';
            app.HinzufgenMenu_DistrLoad.Tag = 'DistrLoadTable';
            app.LschenMenu_DistrLoad = uimenu(app.ContextMenu_DistrLoadTable);
            app.LschenMenu_DistrLoad.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_DistrLoad.Text = 'Löschen';
            app.LschenMenu_DistrLoad.Tag = 'DistrLoadTable';
            % Assign app.ContextMenu_DistrLoadTable
            app.Table_DistrLoad.ContextMenu = app.ContextMenu_DistrLoadTable;
            app.ContextMenu_ForcedDisplTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_ForcedDispl = uimenu(app.ContextMenu_ForcedDisplTable);
            app.HinzufgenMenu_ForcedDispl.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_ForcedDispl.Text = 'Hinzufügen';
            app.HinzufgenMenu_ForcedDispl.Tag = 'ForcedDisplTable';
            app.LschenMenu_ForcedDispl = uimenu(app.ContextMenu_ForcedDisplTable);
            app.LschenMenu_ForcedDispl.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_ForcedDispl.Text = 'Löschen';
            app.LschenMenu_ForcedDispl.Tag = 'ForcedDisplTable';
            % Assign app.ContextMenu_ForcedDisplTable
            app.Table_ForcedDispl.ContextMenu = app.ContextMenu_ForcedDisplTable;
            app.ContextMenu_StatCondTable = uicontextmenu(app.UIFigure);
            app.HinzufgenMenu_StatCond = uimenu(app.ContextMenu_StatCondTable);
            app.HinzufgenMenu_StatCond.MenuSelectedFcn = createCallbackFcn(app, @Button_AddRowPushed, true);
            app.HinzufgenMenu_StatCond.Text = 'Hinzufügen';
            app.HinzufgenMenu_StatCond.Tag = 'StatCondTable';
            app.LschenMenu_StatCond = uimenu(app.ContextMenu_StatCondTable);
            app.LschenMenu_StatCond.MenuSelectedFcn = createCallbackFcn(app, @Button_RemoveRowPushed, true);
            app.LschenMenu_StatCond.Text = 'Löschen';
            app.LschenMenu_StatCond.Tag = 'StatCondTable';
            % Assign app.ContextMenu_StatCondTable
            app.Table_StatCond.ContextMenu = app.ContextMenu_StatCondTable;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end